classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EditPanel               matlab.ui.container.Panel
        EditPanel2              matlab.ui.container.Panel
        BlueEditField           matlab.ui.control.NumericEditField
        BlueEditFieldLabel      matlab.ui.control.Label
        GreenEditField          matlab.ui.control.NumericEditField
        GreenEditFieldLabel     matlab.ui.control.Label
        RedEditField            matlab.ui.control.NumericEditField
        RedEditFieldLabel       matlab.ui.control.Label
        ApplyButton             matlab.ui.control.Button
        ColorNoDropDown         matlab.ui.control.DropDown
        ColorNoDropDownLabel    matlab.ui.control.Label
        ColorPaletteAxes        matlab.ui.control.UIAxes
        ClustersEditField       matlab.ui.control.NumericEditField
        ClustersEditFieldLabel  matlab.ui.control.Label
        SegmentButton           matlab.ui.control.Button
        SegmentedImage          matlab.ui.control.UIAxes
        ResultPanel             matlab.ui.container.Panel
        DownloadButton          matlab.ui.control.Button
        MaskedResultImage       matlab.ui.control.UIAxes
        ResultImage             matlab.ui.control.UIAxes
        OriginalPanel           matlab.ui.container.Panel
        UploadButton            matlab.ui.control.Button
        MaskedOriginalImage     matlab.ui.control.UIAxes
        OriginalImage           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        OriginalData
        SegmentedData
        ResultData
        ColorPaletteData
        ColorValue
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            % Implement the action for the Upload button
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, 'Select an Image File');
            if isequal(filename, 0) || isequal(pathname, 0)
                % User clicked Cancel or closed the dialog
                return;
            end

            % Load the selected image
            fullFilePath = fullfile(pathname, filename);
            app.OriginalData = imread(fullFilePath);
            app.ResultData = app.OriginalData + 0;

            % Display the loaded image in the UI %
            imshow(app.OriginalData, 'Parent', app.OriginalImage);
            imshow(app.ResultData, 'Parent', app.ResultImage');
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            imwrite(app.ResultData, 'swapped.jpg');
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            % Implement the action for the Segment button
            numClusters = (app.ClustersEditField.Value);
            [app.SegmentedData, app.ColorPaletteData] = kmeans_segmentation(app.OriginalData, numClusters);
      
            % Display the segmented image in the UI (Assuming you have an axes component named 'ResultAxes')
            imshow(app.SegmentedData, 'Parent', app.SegmentedImage);
            title(app.SegmentedImage, ['Segmented Image - ', num2str(numClusters), ' clusters']);
            
            % Display the color palette using color patches in the UI (Assuming you have an axes component named 'ColorPaletteAxes')
            imshow(zeros(1, 1, 3), 'Parent', app.ColorPaletteAxes);
            title(app.ColorPaletteAxes, 'Color Palette');
            hold(app.ColorPaletteAxes, 'on');
            for i = 1:numClusters
                rectangle('Parent', app.ColorPaletteAxes, 'Position', [(i-1)*60 + 5, 5, 50, 50], 'FaceColor', app.ColorPaletteData(i, :));
            end
            hold(app.ColorPaletteAxes, 'off');
            axis(app.ColorPaletteAxes, 'off');

            app.ColorNoDropDown.Items = cellstr(num2str((1:numClusters)')); % Convert to cell array of strings
            app.ColorValue = 1
        end

        % Value changed function: ColorNoDropDown
        function ColorNoDropDownValueChanged(app, event)
            app.ColorValue = str2double(app.ColorNoDropDown.Value);

            targetColor = app.ColorPaletteData(app.ColorValue,:);
            disp(targetColor)
            binaryMask = createColorMask(app.OriginalData,targetColor,0.09)
            
            maskedImage = bsxfun(@times, im2double(app.OriginalData), binaryMask);

            imshow(maskedImage, 'Parent', app.MaskedOriginalImage)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            targetColor = app.ColorPaletteData(app.ColorValue,:);
            outputColor = [app.RedEditField.Value/255, app.GreenEditField.Value/255, app.BlueEditField.Value/255];
            
            app.ResultData = colorManipulation(app.ResultData,targetColor,0.09,outputColor)    
            imshow(app.ResultData, 'Parent', app.ResultImage)
                
            binaryMask = createColorMask(app.ResultData,outputColor,0.09)
            maskedImage = bsxfun(@times, im2double(app.ResultData), binaryMask);

            imshow(maskedImage, 'Parent', app.MaskedResultImage)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalPanel = uipanel(app.UIFigure);
            app.OriginalPanel.Title = 'Original';
            app.OriginalImage = uiaxes(app.OriginalPanel);
            app.OriginalImage.XTick = [];
            app.OriginalImage.YTick = [];
            app.MaskedOriginalImage = uiaxes(app.OriginalPanel);
            app.MaskedOriginalImage.XTick = [];
            app.MaskedOriginalImage.XTickLabel = '';
            app.MaskedOriginalImage.YTick = [];
            app.UploadButton = uibutton(app.OriginalPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ResultPanel = uipanel(app.UIFigure);
            app.ResultPanel.Title = 'Result';
            app.ResultImage = uiaxes(app.ResultPanel);
            app.ResultImage.XTick = [];
            app.ResultImage.YTick = [];
            app.MaskedResultImage = uiaxes(app.ResultPanel);
            app.MaskedResultImage.XTick = [];
            app.MaskedResultImage.XTickLabel = '';
            app.MaskedResultImage.YTick = [];
            app.DownloadButton = uibutton(app.ResultPanel, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.EditPanel = uipanel(app.UIFigure);
            app.EditPanel.Title = 'EditPanel';
            app.SegmentedImage = uiaxes(app.EditPanel);
            app.SegmentedImage.XTick = [];
            app.SegmentedImage.YTick = [];
            app.SegmentButton = uibutton(app.EditPanel, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.ClustersEditFieldLabel = uilabel(app.EditPanel);
            app.ClustersEditFieldLabel.Text = 'Clusters';
            app.ClustersEditField = uieditfield(app.EditPanel, 'numeric');
            app.ClustersEditField.Limits = [1 15];
            app.ClustersEditField.Value = 3;
            app.EditPanel2 = uipanel(app.EditPanel);
            app.EditPanel2.Title = 'EditPanel2';
            app.ColorPaletteAxes = uiaxes(app.EditPanel2);
            app.ColorPaletteAxes.XTick = [];
            app.ColorPaletteAxes.YTick = [];
            app.ColorNoDropDownLabel = uilabel(app.EditPanel2);
            app.ColorNoDropDownLabel.Text = 'Color No.';
            app.ColorNoDropDown = uidropdown(app.EditPanel2);
            app.ColorNoDropDown.Items = {'0'};
            app.ColorNoDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorNoDropDownValueChanged, true);
            app.ColorNoDropDown.Value = '0';
            app.ApplyButton = uibutton(app.EditPanel2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RedEditFieldLabel = uilabel(app.EditPanel2);
            app.RedEditFieldLabel.Text = 'Red';
            app.RedEditField = uieditfield(app.EditPanel2, 'numeric');
            app.RedEditField.Limits = [0 255];
            app.GreenEditFieldLabel = uilabel(app.EditPanel2);
            app.GreenEditFieldLabel.Text = 'Green';
            app.GreenEditField = uieditfield(app.EditPanel2, 'numeric');
            app.GreenEditField.Limits = [0 255];
            app.BlueEditFieldLabel = uilabel(app.EditPanel2);
            app.BlueEditFieldLabel.Text = 'Blue';
            app.BlueEditField = uieditfield(app.EditPanel2, 'numeric');
            app.BlueEditField.Limits = [0 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end